classdef final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MaxiumFlightDistanceEditFieldLabel  matlab.ui.control.Label
        MaxiumFlightDistanceEditField  matlab.ui.control.NumericEditField
        VacationPlannerLabel           matlab.ui.control.Label
        DepartureCityDropDownLabel     matlab.ui.control.Label
        DepartureCityDropDown          matlab.ui.control.DropDown
        HottestDestinationsListBoxLabel  matlab.ui.control.Label
        HottestDestinationsListBox     matlab.ui.control.ListBox
        FlightDateDatePickerLabel      matlab.ui.control.Label
        FlightDateDatePicker           matlab.ui.control.DatePicker
        StateDropDownLabel             matlab.ui.control.Label
        StateDropDown                  matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
    end

    
    properties (Access = private)
        airportCodes % Map of airports to their codes
        flightData % Map of codes to flights
        airportList % List of airports for dropdown
        states % List of states and abbreviations
        latLong % Map of codes to latitude and longitude coordinates
    end
    
    methods (Access = private)
        
        function airports = filterState(app,state)
            abbr = app.findStateAbbr(state,1,size(app.states,1));
            if abbr ~= ""
                count = 0;
                for ii = 1:length(app.airportList)
                    city = char(app.airportList(ii));
                    if string(city(length(city)-1:length(city))) == string(abbr)
                        count = count + 1;
                    end
                end
                airports = strings(count,1);
                count = 0;
                for ii = 1:length(app.airportList)
                    city = char(app.airportList(ii));
                    if string(city(length(city)-1:length(city))) == string(abbr)
                        count = count + 1;
                        airports(count) = app.airportList(ii);
                    end
                end
                app.DepartureCityDropDown.Items = airports;
                app.DepartureCityDropDown.Enable = 'on';
                app.DepartureCityDropDownLabel.Enable = 'on';
            else
                app.DepartureCityDropDown.Items = strings(0);
                app.DepartureCityDropDown.Enable = 'off';
                app.DepartureCityDropDownLabel.Enable = 'off';
                app.HottestDestinationsListBox.Items = strings(0);
                app.HottestDestinationsListBox.Enable = 'off';
                app.HottestDestinationsListBoxLabel.Enable = 'off';
                app.plotFlightPath();
            end
        end
        
        function abbr = findStateAbbr(app,state,first,last)
            guess = floor((first+last)/2);
            if first < last && string(app.states{guess,1}) > string(state)
                abbr = app.findStateAbbr(state,first,guess-1);
            elseif first < last && string(app.states{guess,1}) < string(state)
                abbr = app.findStateAbbr(state,guess+1,last);
            else
                if string(app.states{guess,1}) == string(state) 
                    abbr = app.states{guess,2};
                else
                    abbr = "";
                end
            end
        end
        
        function statesProcessed = processStates(app,stateData)
            statesProcessed = stateData;
            for ii = 1:length(statesProcessed)
                names = split(statesProcessed{ii,1});
                if length(names) == 1
                    name = names{1};
                    name(2:end) = lower(name(2:end));
                elseif length(names) == 2
                    firstName = names{1};
                    firstName(2:end) = lower(firstName(2:end));
                    secondName = names{2};
                    secondName(2:end) = lower(secondName(2:end));
                    name = [firstName ' ' secondName];
                else
                    firstName = names{1};
                    firstName(2:end) = lower(firstName(2:end));
                    secondName = names{3};
                    secondName(2:end) = lower(secondName(2:end));
                    name = [firstName ' of ' secondName];
                end
                statesProcessed(ii,1) = {name};
            end
        end
        
        function fits = leastSquares(app,groups,metaData)
            fits = cell(size(unique(groups)));
            for ii = 1:length(fits)
                groupData = metaData(groups == ii,2);
                numRows = 0;
                for jj = 1:length(groupData)
                    numRows = numRows + size(groupData{jj},1);
                end
                finalData = zeros(numRows,7);
                numRows = 1;
                for jj = 1:length(groupData)
                    finalData(numRows:numRows+size(groupData{jj},1)-1,:) = groupData{jj};
                    numRows = numRows + size(groupData{jj},1);
                end
                passengers = finalData(:,1);
                times = finalData(passengers ~= 0,5);
                passengers = passengers(passengers ~= 0);
                if ~isempty(passengers)
                    val = 1;
                    if size(finalData,1) == 1
                        val = 0;
                    end
                    timestr = num2str(times);
                    y = timestr(:,1:4);
                    m = timestr(:,5:6);
                    times = (str2num(y)-1990)*12+str2num(m);
                    Z = times.^(0:val);
                    coeffs = Z\passengers;
                    fits(ii) = {@(t) t.^(0:val)*coeffs};
                else
                    fits(ii) = {@(t) 0};
                end
            end
        end
        
        function plotFlightPath(app)
            departCity = app.DepartureCityDropDown.Value;
            departCode = app.airportCodes.getCode(departCity);
            arriveCity = app.HottestDestinationsListBox.Value;
            arriveCode = app.airportCodes.getCode(arriveCity);
            if departCode ~= ""
                [lat1,long1] = app.airportCodes.getLatLong(departCode);
                [lat2,long2] = app.airportCodes.getLatLong(arriveCode);
            else
                lat1 = NaN;
                long1 = NaN;
                lat2 = NaN;
                long2 = NaN;
            end
            gx = geoaxes(app.Panel,'Basemap','landcover');
            geoplot(gx,[lat1;lat2],[long1;long2],'go-');
            geolimits(gx,[17.5 67],[-160 -65]);      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = load("processedData.mat");
            app.airportCodes = data.airportCodes;
            app.flightData = data.theMap;
            app.states = app.processStates(data.states);
            airports = data.airportCodes.airportNameTable(~cellfun('isempty',data.airportCodes.airportNameTable));
            app.airportList = sort(airports(1:end/2));
            app.DepartureCityDropDown.Items = app.airportList;
            departCode = app.airportCodes.getCode(app.DepartureCityDropDown.Items(1));
            arrivals = app.flightData.get(departCode);            airports = strings(size(arrivals,1),1);
            for ii = 1:size(arrivals,1)
                airports(ii) = app.airportCodes.getAirport(arrivals{ii,1});
            end
            app.HottestDestinationsListBox.Items = airports;
            time = datestr(now);
            time = time(1:find(time == ' ',1));
            app.FlightDateDatePicker.Value = datetime(time);
            app.StateDropDown.Items = app.states(:,1);
            app.StateDropDown.Value = app.StateDropDown.Items(1);
            app.StateDropDownValueChanged();
            app.plotFlightPath();
        end

        % Value changed function: DepartureCityDropDown
        function DepartureCityDropDownValueChanged(app, event)
            departCity = app.DepartureCityDropDown.Value;
            departCode = app.airportCodes.getCode(departCity);
            if departCode ~= ""
                arrivals = app.flightData.get(departCode);
                airports = strings(size(arrivals,1),1);
                for ii = 1:size(arrivals,1)
                    airports(ii) = app.airportCodes.getAirport(arrivals{ii,1});
                end
                [groups,sortedAirports] = findgroups(airports);
                fits = app.leastSquares(groups,arrivals);
                popularity = zeros(size(fits));
                distances = zeros(size(fits));
                for ii = 1:length(fits)
                    time = app.FlightDateDatePicker.Value;
                    popularity(ii) = fits{ii}((str2double(datestr(time,'yyyy'))-1990)*12+str2double(datestr(time,'mm')));
                    groupData = arrivals(groups == ii,2);
                    numRows = 0;
                    for jj = 1:length(groupData)
                        numRows = numRows + size(groupData{jj},1);
                    end
                    finalData = zeros(numRows,7);
                    numRows = 1;
                    for jj = 1:length(groupData)
                        finalData(numRows:numRows+size(groupData{jj},1)-1,:) = groupData{jj};
                        numRows = numRows + size(groupData{jj},1);
                    end
                    distances(ii) = mean(finalData(:,4));
                end
                sortedAirports = sortedAirports(distances < app.MaxiumFlightDistanceEditField.Value);
                popularity = popularity(distances < app.MaxiumFlightDistanceEditField.Value);
                [~,ind] = sort(popularity);
                sortedAirports = sortedAirports(ind);
                sortedAirports = sortedAirports(sortedAirports ~= departCity);
                app.HottestDestinationsListBox.Items = sortedAirports;
                if length(app.HottestDestinationsListBox.Items) > 10
                    app.HottestDestinationsListBox.Items = app.HottestDestinationsListBox.Items(1:10);
                end
                if ~isempty(app.HottestDestinationsListBox.Items)
                    app.HottestDestinationsListBox.Value = app.HottestDestinationsListBox.Items(1);
                    app.HottestDestinationsListBox.Enable = 'on';
                    app.HottestDestinationsListBoxLabel.Enable = 'on';
                else
                    app.HottestDestinationsListBox.Value = strings(0);
                    app.HottestDestinationsListBox.Enable = 'off';
                    app.HottestDestinationsListBoxLabel.Enable = 'off';
                end
            else
                app.HottestDestinationsListBox.Items = strings(0);
                app.HottestDestinationsListBox.Enable = 'off';
                app.HottestDestinationsListBoxLabel.Enable = 'off';
            end
            app.plotFlightPath();
        end

        % Value changed function: StateDropDown
        function StateDropDownValueChanged(app, event)
            value = app.StateDropDown.Value;
            app.filterState(value);
            if ~isempty(app.DepartureCityDropDown.Items)
                app.DepartureCityDropDown.Value = app.DepartureCityDropDown.Items(1);
                app.DepartureCityDropDownValueChanged()
            end
        end

        % Value changed function: FlightDateDatePicker
        function FlightDateDatePickerValueChanged(app, event)
            app.DepartureCityDropDownValueChanged();
        end

        % Value changed function: MaxiumFlightDistanceEditField
        function MaxiumFlightDistanceEditFieldValueChanged(app, event)
            app.DepartureCityDropDownValueChanged();
        end

        % Value changed function: HottestDestinationsListBox
        function HottestDestinationsListBoxValueChanged(app, event)
            app.plotFlightPath();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MaxiumFlightDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.MaxiumFlightDistanceEditFieldLabel.Text = 'Maxium Flight Distance';
            app.MaxiumFlightDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxiumFlightDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MaxiumFlightDistanceEditFieldValueChanged, true);
            app.MaxiumFlightDistanceEditField.Value = Inf;
            app.VacationPlannerLabel = uilabel(app.UIFigure);
            app.VacationPlannerLabel.Text = 'Vacation Planner';
            app.DepartureCityDropDownLabel = uilabel(app.UIFigure);
            app.DepartureCityDropDownLabel.Text = 'Departure City';
            app.DepartureCityDropDown = uidropdown(app.UIFigure);
            app.DepartureCityDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5'};
            app.DepartureCityDropDown.Editable = 'on';
            app.DepartureCityDropDown.ValueChangedFcn = createCallbackFcn(app, @DepartureCityDropDownValueChanged, true);
            app.HottestDestinationsListBoxLabel = uilabel(app.UIFigure);
            app.HottestDestinationsListBoxLabel.Text = 'Hottest Destinations';
            app.HottestDestinationsListBox = uilistbox(app.UIFigure);
            app.HottestDestinationsListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5'};
            app.HottestDestinationsListBox.ValueChangedFcn = createCallbackFcn(app, @HottestDestinationsListBoxValueChanged, true);
            app.FlightDateDatePickerLabel = uilabel(app.UIFigure);
            app.FlightDateDatePickerLabel.Text = 'Flight Date';
            app.FlightDateDatePicker = uidatepicker(app.UIFigure);
            app.FlightDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @FlightDateDatePickerValueChanged, true);
            app.StateDropDownLabel = uilabel(app.UIFigure);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.UIFigure);
            app.StateDropDown.Editable = 'on';
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateDropDownValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end